classdef icalPR670 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SaveCSVMenu                  matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        EditMenu                     matlab.ui.container.Menu
        ActionsMenu                  matlab.ui.container.Menu
        InitializeMenu               matlab.ui.container.Menu
        MeasureMenu                  matlab.ui.container.Menu
        PlotMenu                     matlab.ui.container.Menu
        EnterremoteMenu              matlab.ui.container.Menu
        QuitremoteMenu               matlab.ui.container.Menu
        FlywheelMenu                 matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        AboutFlywheelMenu            matlab.ui.container.Menu
        AboutPTBMenu                 matlab.ui.container.Menu
        FileInformationPanel         matlab.ui.container.Panel
        CommentTextAreaLabel         matlab.ui.control.Label
        CommentTextArea              matlab.ui.control.TextArea
        FilenameEditFieldLabel       matlab.ui.control.Label
        FilenameEditField            matlab.ui.control.EditField
        DirectoryEditFieldLabel      matlab.ui.control.Label
        DirectoryEditField           matlab.ui.control.EditField
        icalPR670Label               matlab.ui.control.Label
        SpectroradiometerPR670Panel  matlab.ui.container.Panel
        MeasureSaveButton            matlab.ui.control.Button
        PlotButton                   matlab.ui.control.Button
        InitializeButton             matlab.ui.control.Button
        RepeatsSpinnerLabel          matlab.ui.control.Label
        RepeatsSpinner               matlab.ui.control.Spinner
        ReadyLampLabel               matlab.ui.control.Label
        ReadyLamp                    matlab.ui.control.Lamp
        AperturesizeDropDownLabel    matlab.ui.control.Label
        AperturesizeDropDown         matlab.ui.control.DropDown
        spdGraph                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        pr670; % Serial port to the pr670
    end
    
    methods (Access = private)
        
        
        function icalPlot(app,varargin)
            % Plot a file from the current selected directory.
            p = inputParser;
            p.addRequired('app',@(x)(isa(x,'icalPR670')));
            p.addParameter('file',true,@islogical);
            p.parse(app,varargin{:});
            if p.Results.file
                chdir(app.DirectoryEditField.Value);
                fullName = vcSelectDataFile('stayput','r','mat','Select spd file');
                [spd.energy,spd.wave] = ieReadSpectra(fullName);
                [~,n,e] = fileparts(fullName);
                fname = [n,e];
            else
                % Current user data
                spd  = app.spdGraph.UserData;
                fname = app.FilenameEditField.Value;
            end
            
            % Do the plot
            plot(app.spdGraph,spd.wave,spd.energy);
            app.spdGraph.Title.String = sprintf('%s',fname);
            grid(app.spdGraph,'on');
        end
        
        function fullName = icalSave(app,varargin)
            % Save the data from the spd in a txt or mat file.
            p = inputParser;
            p.addRequired('app');
            vFunc = @(x)(ismember(x,{'txt','json','mat','csv'}));
            p.addParameter('extension','mat',vFunc)
            p.parse(app,varargin{:});
            ext = p.Results.extension;
            
            spd = app.spdGraph.UserData;
            
            fullName = icalFilename(app,'extension',ext);
            
            comment = app.CommentTextArea.Value;
            if exist(fullName,'file')
                % GIve user the chance to rename the file or overwrite
                answer = questdlg('Overwrite?','File save','Yes','No','No');
                
                if strcmp('No',answer)
                    prompt = {'File name'}; dTitle = 'New file name';
                    dims = [1 35]; defName = {'tmp'};
                    str = inputdlg(prompt, dTitle,dims,defName);
                    if isempty(str), disp('User canceled'); return; end
                    
                    % Update the window.
                    fname = str{1};
                    directory = app.DirectoryEditField.Value;
                    fullName = fullfile(directory,fname);
                    [p,n,e] = fileparts(fullName);
                    fname = [n,'.',ext];
                    if ~isequal(e,ext)
                        fullName = fullfile(p,fname);
                    end
                    app.FilenameEditField.Value = fname;
                    
                end
            end
            
            switch ext
                case 'mat'
                    % Save raw data as an ISETCam spectral file
                    ieSaveSpectralFile(spd.wave,spd.energy,comment,fullName);
                case {'txt','csv'}
                    % Save as CSV text file for Flywheel upload (probably)
                    writematrix([spd.wave(:),spd.energy(:)],fullName);
                otherwise
                    error('Extension not managed %s\n',ext);
            end
        end
        
        function fullName = icalFilename(app,varargin)
            % Create a default file name with some extension.
            % By default, .mat, extension
            p = inputParser;
            p.addRequired('app');
            vFunc = @(x)(ismember(x,{'txt','json','mat','csv'}));
            p.addParameter('extension','mat',vFunc);
            p.addParameter('new',false,@islogical);
            p.parse(app,varargin{:});
            ext = p.Results.extension;
            forceNew = p.Results.new;
            
            directory = app.DirectoryEditField.Value;
            if isempty(directory)
                % By default the directory is in local and with the date.
                tmp = datestr(now,'yyyy-mm-dd');
                directory = fullfile(icalRootPath,'local',tmp);
                if ~exist(directory,'dir'), mkdir(directory); end
                app.DirectoryEditField.Value = directory;
            end
            
            fname = app.FilenameEditField.Value;
            if isempty(fname) || forceNew
                tmp = datestr(now,'yyyy-mm-dd-HH-MM-SS');
                fname = sprintf('spd-%s',tmp);
                app.FilenameEditField.Value = fname;
            end
            
            fullName = fullfile(directory,fname);
            [p,n,e] = fileparts(fullName);
            
            if ~isequal(e,ext)
                fullName = fullfile(p,[n,'.',ext]);
            end
        end
        
    end
    
    methods (Access = public)
        % Maybe some of the functions about should be public?
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % When the app is opened, we do these things.
            app.icalFilename('new',true);
            assignin('base',"icalApp",app);
        end

        % Button pushed function: MeasureSaveButton
        function btnMeasure(app, event)
            % wave = prGetWave(app);
            
            app.ReadyLampLabel.Text = 'Measuring';
            app.ReadyLamp.Color = [1 0 0];
            
            icalPR670CMD(app.pr670,'remote');
            spd    = icalPR670CMD(app.pr670,'measure read spd');
            
            app.spdGraph.UserData = spd;
            
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = [0 1 0];
            
            % Save it to a file
            fname = icalSave(app);
            fprintf('SPD saved in %s\n',fname);
                        
            % Normally, we plot after measuring.  Just use the spd data in
            % the app, do not read a file.
            icalPlot(app,'file',false);
            
        end

        % Button pushed function: InitializeButton
        function btnInitPR670(app, event)
            
            if isa(app.pr670,'internal.Serialport')
                disp('Serial port already opened.')
            else
                % Open the COM port to the PR670
                app.pr670 = icalPR670Init;
                if isempty(app.pr670)
                    disp('Make sure the PR670 is correctly plugged in.')
                    disp('Particularly the gray plug in the PR670.')
                    warning('Error initializing PR670');
                    return;
                else
                    disp('PR670 com port opened.')
                end
            end
            
            % Set the aperture as in the window
            app.apertureSize;
            
            % Clear any stray stuff in the read buffer
            icalPR670CMD(app.pr670,'clear read buffer');
            
            disp('Setting remote mode (controlled by this program)')
            icalPR670CMD(app.pr670,'remote');
            
            % Create a local folder with the date where the measurements
            % will be stored.  Change into that folder.
            dirName = datestr(now,'yyyy-mm-dd');
            folderName = fullfile(icalRootPath, 'local', dirName);
            if ~exist(folderName, 'dir'), mkdir(folderName); end
            cd(folderName);
            
        end

        % Button pushed function: PlotButton
        function btnPlot(app, event)
            icalPlot(app);
        end

        % Menu selected function: SaveMenu
        function fileSave(app, event)
            fname = icalSave(app,'extension','mat');
            fprintf('SPD saved in %s\n',fname);
        end

        % Menu selected function: LoadMenu
        function menuFileLoad(app, event)
            % Load a local file for plotting and perhaps uploading
            wave = prGetWave(app);
            [spd,~,comment,fname] = ieReadSpectra('',wave,0);
            
            [~,~,e] = fileparts(fname);
            
            if isempty(spd)
                return;
            elseif ~isequal(e,'.mat')
                warndlg('Load only mat-files: %s',fname);
                return;
            end
            
            app.spdGraph.UserData = spd;
            app.CommentTextArea.Value = comment;
            
            % Put the name in the app fields
            [p,n,e] = fileparts(fname);
            app.FilenameEditField.Value = [n,e];
            app.DirectoryEditField.Value = p;
            
            % Put it in the graph window
            icalPlot(app);
        end

        % Menu selected function: FlywheelMenu
        function menuActionsFlywheel(app, event)
            % Upload the current spd data to flywheel
            % We will also want a bulk convert and upload of multiple
            % files, perhaps.
            icalSave(app,'extension','csv');
        end

        % Menu selected function: SaveCSVMenu
        function menuFileSaveText(app, event)
            fname = icalSave(app,'extension','csv');
            fprintf('Saved %s\n',fname);
        end

        % Menu selected function: QuitremoteMenu
        function localmode(app, event)
            icalPR670CMD(app.pr670,'local');
        end

        % Menu selected function: ExitMenu
        function exit(app, event)
            % Could do more cleanup on exit.
            icalPR670CMD(app.pr670,'local');
            app.pr670 = [];  % Close the serial port.
            delete(app);     % Delete the app
        end

        % Menu selected function: EnterremoteMenu
        function remotemode(app, event)
            icalPR670CMD(app.pr670,'remote');
        end

        % Value changed function: AperturesizeDropDown
        function apertureSize(app, event)
            value = app.AperturesizeDropDown.Value;
            switch(value)
                case '1 deg'
                    icalPR670CMD(app.pr670,'aperture large');
                case '0.5 deg'
                    icalPR670CMD(app.pr670,'aperture small');
                case '0.125 deg'
                    icalPR670CMD(app.pr670,'aperture really tiny');
                otherwise
                    warning('Unknown pulldown for aperture.')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoad, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @fileSave, true);
            app.SaveMenu.Text = 'Save';
            app.SaveCSVMenu = uimenu(app.FileMenu);
            app.SaveCSVMenu.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveText, true);
            app.SaveCSVMenu.Text = 'Save CSV';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @exit, true);
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ActionsMenu = uimenu(app.UIFigure);
            app.ActionsMenu.Text = 'Actions';
            app.InitializeMenu = uimenu(app.ActionsMenu);
            app.InitializeMenu.Text = 'Initialize';
            app.MeasureMenu = uimenu(app.ActionsMenu);
            app.MeasureMenu.Text = 'Measure';
            app.PlotMenu = uimenu(app.ActionsMenu);
            app.PlotMenu.Separator = 'on';
            app.PlotMenu.Text = 'Plot';
            app.EnterremoteMenu = uimenu(app.ActionsMenu);
            app.EnterremoteMenu.MenuSelectedFcn = createCallbackFcn(app, @remotemode, true);
            app.EnterremoteMenu.Text = 'Enter remote';
            app.QuitremoteMenu = uimenu(app.ActionsMenu);
            app.QuitremoteMenu.MenuSelectedFcn = createCallbackFcn(app, @localmode, true);
            app.QuitremoteMenu.Text = 'Quit remote';
            app.FlywheelMenu = uimenu(app.ActionsMenu);
            app.FlywheelMenu.MenuSelectedFcn = createCallbackFcn(app, @menuActionsFlywheel, true);
            app.FlywheelMenu.Separator = 'on';
            app.FlywheelMenu.Text = 'Flywheel';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutFlywheelMenu = uimenu(app.HelpMenu);
            app.AboutFlywheelMenu.Text = 'About Flywheel';
            app.AboutPTBMenu = uimenu(app.HelpMenu);
            app.AboutPTBMenu.Text = 'About PTB';
            app.FileInformationPanel = uipanel(app.UIFigure);
            app.FileInformationPanel.Title = 'File Information';
            app.CommentTextAreaLabel = uilabel(app.FileInformationPanel);
            app.CommentTextAreaLabel.Text = 'Comment';
            app.CommentTextArea = uitextarea(app.FileInformationPanel);
            app.FilenameEditFieldLabel = uilabel(app.FileInformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.FileInformationPanel, 'text');
            app.DirectoryEditFieldLabel = uilabel(app.FileInformationPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.FileInformationPanel, 'text');
            app.icalPR670Label = uilabel(app.UIFigure);
            app.icalPR670Label.Text = 'icalPR670';
            app.SpectroradiometerPR670Panel = uipanel(app.UIFigure);
            app.SpectroradiometerPR670Panel.Title = 'Spectroradiometer (PR 670)';
            app.MeasureSaveButton = uibutton(app.SpectroradiometerPR670Panel, 'push');
            app.MeasureSaveButton.ButtonPushedFcn = createCallbackFcn(app, @btnMeasure, true);
            app.MeasureSaveButton.Text = 'Measure + Save';
            app.PlotButton = uibutton(app.SpectroradiometerPR670Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @btnPlot, true);
            app.PlotButton.Text = 'Plot';
            app.InitializeButton = uibutton(app.SpectroradiometerPR670Panel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @btnInitPR670, true);
            app.InitializeButton.Text = 'Initialize';
            app.RepeatsSpinnerLabel = uilabel(app.SpectroradiometerPR670Panel);
            app.RepeatsSpinnerLabel.Text = 'Repeats';
            app.RepeatsSpinner = uispinner(app.SpectroradiometerPR670Panel);
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.ReadyLamp.Tooltip = {'Status of the PR670'};
            app.AperturesizeDropDownLabel = uilabel(app.UIFigure);
            app.AperturesizeDropDownLabel.Text = 'Aperture size';
            app.AperturesizeDropDown = uidropdown(app.UIFigure);
            app.AperturesizeDropDown.Items = {'1 deg', '0.5 deg', '0.125 deg'};
            app.AperturesizeDropDown.ValueChangedFcn = createCallbackFcn(app, @apertureSize, true);
            app.AperturesizeDropDown.Value = '1 deg';
            app.spdGraph = uiaxes(app.UIFigure);
            title(app.spdGraph, 'PR 670 Data')
            xlabel(app.spdGraph, 'Wave (nm)')
            ylabel(app.spdGraph, 'Energy (watts/sr/nm/m^2)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = icalPR670

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end